classdef DOVE_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        wcg             matlab.ui.control.EditField
        wbg             matlab.ui.control.EditField
        wag             matlab.ui.control.EditField
        wagref          matlab.ui.control.EditField
        Gcg             matlab.ui.control.EditField
        Gbg             matlab.ui.control.EditField
        Gag             matlab.ui.control.EditField
        Gcb             matlab.ui.control.EditField
        wagalt          matlab.ui.control.EditField
        Gagref          matlab.ui.control.EditField
        Gagalt          matlab.ui.control.EditField
        ADIR1           matlab.ui.control.EditField
        ADIR2           matlab.ui.control.EditField
        ADRam           matlab.ui.control.EditField
        ACARSref        matlab.ui.control.EditField
        ACARSalt        matlab.ui.control.EditField
        ASIVE           matlab.ui.control.EditField
        sigw1           matlab.ui.control.EditField
        sigw2           matlab.ui.control.EditField
        sigw3           matlab.ui.control.EditField
        T21             matlab.ui.control.EditField
        T32             matlab.ui.control.EditField
        Abg             matlab.ui.control.EditField
        samnirfile      matlab.ui.control.EditField
        samemfile       matlab.ui.control.EditField
        outfilepre      matlab.ui.control.EditField
        Nsam            matlab.ui.control.EditField
        Nbg             matlab.ui.control.EditField
        w2beg           matlab.ui.control.EditField
        ls              matlab.ui.control.EditField
        theta31         matlab.ui.control.EditField
        theta21         matlab.ui.control.EditField
        lfw             matlab.ui.control.EditField
        Afw             matlab.ui.control.EditField
        lbw             matlab.ui.control.EditField
        Abw             matlab.ui.control.EditField
        samnvisfile     matlab.ui.control.EditField
        fwnvisfile      matlab.ui.control.EditField
        fwnirfile       matlab.ui.control.EditField
        fwemfile        matlab.ui.control.EditField
        bwnvisfile      matlab.ui.control.EditField
        bwnirfile       matlab.ui.control.EditField
        bwemfile        matlab.ui.control.EditField
        Nyq             matlab.ui.control.EditField
        res             matlab.ui.control.EditField
        comput          matlab.ui.control.Button
        text3           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text10          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        PMTqefile       matlab.ui.control.EditField
        text12          matlab.ui.control.Label
        text13          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text17          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        Nref            matlab.ui.control.EditField
        text20          matlab.ui.control.Label
        text21          matlab.ui.control.Label
        text22          matlab.ui.control.Label
        text23          matlab.ui.control.Label
        text24          matlab.ui.control.Label
        text25          matlab.ui.control.Label
        text29          matlab.ui.control.Label
        w2end           matlab.ui.control.EditField
        w2sp            matlab.ui.control.EditField
        text30          matlab.ui.control.Label
        text31          matlab.ui.control.Label
        text33          matlab.ui.control.Label
        text35          matlab.ui.control.Label
        text36          matlab.ui.control.Label
        text37          matlab.ui.control.Label
        text38          matlab.ui.control.Label
        text39          matlab.ui.control.Label
        text40          matlab.ui.control.Label
        text41          matlab.ui.control.Label
        text45          matlab.ui.control.Label
        text46          matlab.ui.control.Label
        text47          matlab.ui.control.Label
        ACARS           matlab.ui.control.EditField
        text48          matlab.ui.control.Label
        text49          matlab.ui.control.Label
        text50          matlab.ui.control.Label
        anharmw1        matlab.ui.control.EditField
        anharmw2        matlab.ui.control.EditField
        Ratio           matlab.ui.control.EditField
        text51          matlab.ui.control.Label
        text52          matlab.ui.control.Label
        text53          matlab.ui.control.Label
        text54          matlab.ui.control.Label
        text55          matlab.ui.control.Label
        text56          matlab.ui.control.Label
        text57          matlab.ui.control.Label
        text58          matlab.ui.control.Label
        text59          matlab.ui.control.Label
        text60          matlab.ui.control.Label
        text61          matlab.ui.control.Label
        text62          matlab.ui.control.Label
        text63          matlab.ui.control.Label
        text64          matlab.ui.control.Label
        text65          matlab.ui.control.Label
        text66          matlab.ui.control.Label
        text67          matlab.ui.control.Label
        text68          matlab.ui.control.Label
        text69          matlab.ui.control.Label
        text70          matlab.ui.control.Label
        text71          matlab.ui.control.Label
        text72          matlab.ui.control.Label
        text73          matlab.ui.control.Label
        text74          matlab.ui.control.Label
        text75          matlab.ui.control.Label
        text76          matlab.ui.control.Label
        text78          matlab.ui.control.Label
        text79          matlab.ui.control.Label
        text80          matlab.ui.control.Label
        text84          matlab.ui.control.Label
        text85          matlab.ui.control.Label
        text86          matlab.ui.control.Label
        text87          matlab.ui.control.Label
        text88          matlab.ui.control.Label
        text89          matlab.ui.control.Label
        text90          matlab.ui.control.Label
        text93          matlab.ui.control.Label
        text94          matlab.ui.control.Label
        text98          matlab.ui.control.Label
        text99          matlab.ui.control.Label
        text100         matlab.ui.control.Label
        text101         matlab.ui.control.Label
        text102         matlab.ui.control.Label
        text103         matlab.ui.control.Label
        text105         matlab.ui.control.Label
        text106         matlab.ui.control.Label
        text107         matlab.ui.control.Label
        text108         matlab.ui.control.Label
        text111         matlab.ui.control.Label
        text112         matlab.ui.control.Label
        text113         matlab.ui.control.Label
        text114         matlab.ui.control.Label
        text115         matlab.ui.control.Label
        text116         matlab.ui.control.Label
        text117         matlab.ui.control.Label
        text118         matlab.ui.control.Label
        text119         matlab.ui.control.Label
        text120         matlab.ui.control.Label
        text121         matlab.ui.control.Label
        text122         matlab.ui.control.Label
        text123         matlab.ui.control.Label
        text124         matlab.ui.control.Label
        text126         matlab.ui.control.Label
        text127         matlab.ui.control.Label
        text128         matlab.ui.control.Label
        text130         matlab.ui.control.Label
        text131         matlab.ui.control.Label
        text132         matlab.ui.control.Label
        text133         matlab.ui.control.Label
        text137         matlab.ui.control.Label
        Nalt            matlab.ui.control.EditField
        text138         matlab.ui.control.Label
        text139         matlab.ui.control.Label
        text140         matlab.ui.control.Label
        w3              matlab.ui.control.EditField
        text141         matlab.ui.control.Label
        text142         matlab.ui.control.Label
        loadparams      matlab.ui.control.EditField
        text143         matlab.ui.control.Label
        toggleload      matlab.ui.control.Button
        text144         matlab.ui.control.Label
        text146         matlab.ui.control.Label
        text147         matlab.ui.control.Label
        datafile        matlab.ui.control.EditField
        text148         matlab.ui.control.Label
        text149         matlab.ui.control.Label
        text150         matlab.ui.control.Label
        etaab           matlab.ui.control.EditField
        text151         matlab.ui.control.Label
        lambdabg        matlab.ui.control.EditField
        lambdaag        matlab.ui.control.EditField
        kappaag         matlab.ui.control.EditField
        kappabg         matlab.ui.control.EditField
        text152         matlab.ui.control.Label
        text153         matlab.ui.control.Label
        text154         matlab.ui.control.Label
        text155         matlab.ui.control.Label
        text156         matlab.ui.control.Label
        text157         matlab.ui.control.Label
        text158         matlab.ui.control.Label
        kappa           matlab.ui.control.EditField
        FWHM            matlab.ui.control.EditField
        lambda          matlab.ui.control.EditField
        lamcmpt         matlab.ui.control.Button
        text159         matlab.ui.control.Label
        text160         matlab.ui.control.Label
        text161         matlab.ui.control.Label
        text163         matlab.ui.control.Label
        edit101         matlab.ui.control.Label
        text165         matlab.ui.control.Label
        path            matlab.ui.control.EditField
        text166         matlab.ui.control.Label
        delta           matlab.ui.control.EditField
        pure            matlab.ui.control.EditField
        text167         matlab.ui.control.Label
        text168         matlab.ui.control.Label
        text169         matlab.ui.control.Label
        text170         matlab.ui.control.Label
        saveparams      matlab.ui.control.CheckBox
        leastsquareson  matlab.ui.control.CheckBox
        E1flag0         matlab.ui.control.CheckBox
        Label           matlab.ui.control.Label
        E1flag1         matlab.ui.control.NumericEditField
        E2flag0         matlab.ui.control.CheckBox
        Label_2         matlab.ui.control.Label
        E2flag1         matlab.ui.control.NumericEditField
        text171_2       matlab.ui.control.TextArea
        text171_3       matlab.ui.control.TextArea
        text171_4       matlab.ui.control.TextArea
        text171         matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        function dtf_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to dtf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function edit74_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit74 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function fwhm_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to fwhm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function gam_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function gamstar_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function nsamoff_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to nsamoff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function offset_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to offset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function w1beg_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to w1beg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function w1end_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to w1end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function xintbg_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to xintbg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DOVE_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to DOVE (see VARARGIN)
%             
%             % Choose default command line output for DOVE
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);

        app.comput.Callback = 'DOVEcmpt';
        app.toggleload.Callback = 'frontpanelload';
        app.lamcmpt.Callback = 'lambdacompute';
        end

        % Callback function
        function DOVEcmp(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DOVE';
            app.wcg = uieditfield(app.figure1, 'text');
            app.wcg.Value = '0';
            app.wbg = uieditfield(app.figure1, 'text');
            app.wbg.Value = '0';
            app.wag = uieditfield(app.figure1, 'text');
            app.wag.Value = '0';
            app.wagref = uieditfield(app.figure1, 'text');
            app.wagref.Value = '0';
            app.Gcg = uieditfield(app.figure1, 'text');
            app.Gcg.Value = '0';
            app.Gbg = uieditfield(app.figure1, 'text');
            app.Gbg.Value = '0';
            app.Gag = uieditfield(app.figure1, 'text');
            app.Gag.Value = '0';
            app.Gcb = uieditfield(app.figure1, 'text');
            app.Gcb.Value = '0';
            app.wagalt = uieditfield(app.figure1, 'text');
            app.wagalt.Value = '0';
            app.Gagref = uieditfield(app.figure1, 'text');
            app.Gagref.Value = '0';
            app.Gagalt = uieditfield(app.figure1, 'text');
            app.Gagalt.Value = '0';
            app.ADIR1 = uieditfield(app.figure1, 'text');
            app.ADIR1.Value = '0';
            app.ADIR2 = uieditfield(app.figure1, 'text');
            app.ADIR2.Value = '0';
            app.ADRam = uieditfield(app.figure1, 'text');
            app.ADRam.Value = '0';
            app.ACARSref = uieditfield(app.figure1, 'text');
            app.ACARSref.Value = '1';
            app.ACARSalt = uieditfield(app.figure1, 'text');
            app.ACARSalt.Value = '0';
            app.ASIVE = uieditfield(app.figure1, 'text');
            app.ASIVE.Value = '0';
            app.sigw1 = uieditfield(app.figure1, 'text');
            app.sigw1.Tooltip = 'variance of the form E(t) = exp(-t^2/(2sig^2))';
            app.sigw1.Value = '1.05';
            app.sigw2 = uieditfield(app.figure1, 'text');
            app.sigw2.Value = '1.05';
            app.sigw3 = uieditfield(app.figure1, 'text');
            app.sigw3.Tooltip = 'see ''sigw1''';
            app.sigw3.Value = '1.05';
            app.T21 = uieditfield(app.figure1, 'text');
            app.T21.Tooltip = 'delay between pulses 2 and 1.  positive = pulse 2 comes after 1.';
            app.T21.Value = '0';
            app.T32 = uieditfield(app.figure1, 'text');
            app.T32.Tooltip = 'delay between pulses 3 and 2.  positive = 3 comes after 2.';
            app.T32.Value = '0';
            app.Abg = uieditfield(app.figure1, 'text');
            app.Abg.Value = '0';
            app.samnirfile = uieditfield(app.figure1, 'text');
            app.samnirfile.Value = 'nsir';
            app.samemfile = uieditfield(app.figure1, 'text');
            app.samemfile.Value = 'emsample';
            app.outfilepre = uieditfield(app.figure1, 'text');
            app.outfilepre.Value = 'output';
            app.Nsam = uieditfield(app.figure1, 'text');
            app.Nsam.Value = '0.154';
            app.Nbg = uieditfield(app.figure1, 'text');
            app.Nbg.Value = '15.1';
            app.w2beg = uieditfield(app.figure1, 'text');
            app.w2beg.Value = '1480';
            app.ls = uieditfield(app.figure1, 'text');
            app.ls.Value = '0.0';
            app.theta31 = uieditfield(app.figure1, 'text');
            app.theta31.Value = '-2.0';
            app.theta21 = uieditfield(app.figure1, 'text');
            app.theta21.Value = '9.0';
            app.lfw = uieditfield(app.figure1, 'text');
            app.lfw.Value = '0.015';
            app.Afw = uieditfield(app.figure1, 'text');
            app.Afw.Value = '0.0';
            app.lbw = uieditfield(app.figure1, 'text');
            app.lbw.Value = '0.0';
            app.Abw = uieditfield(app.figure1, 'text');
            app.Abw.Value = '0.0';
            app.samnvisfile = uieditfield(app.figure1, 'text');
            app.samnvisfile.Value = 'nsvis';
            app.fwnvisfile = uieditfield(app.figure1, 'text');
            app.fwnvisfile.Value = 'sapno';
            app.fwnirfile = uieditfield(app.figure1, 'text');
            app.fwnirfile.Value = 'sapno';
            app.fwemfile = uieditfield(app.figure1, 'text');
            app.fwemfile.Value = 'sapem';
            app.bwnvisfile = uieditfield(app.figure1, 'text');
            app.bwnvisfile.Value = 'sapno';
            app.bwnirfile = uieditfield(app.figure1, 'text');
            app.bwnirfile.Value = 'sapno';
            app.bwemfile = uieditfield(app.figure1, 'text');
            app.bwemfile.Value = 'sapem';
            app.Nyq = uieditfield(app.figure1, 'text');
            app.Nyq.Tooltip = 'make sure w2 beg and w2end are within Nyq of DOVE';
            app.Nyq.Value = '100';
            app.res = uieditfield(app.figure1, 'text');
            app.res.Tooltip = 'For optimal results, make sure w2 spacing is greater or equal to res';
            app.res.Value = '1';
            app.comput = uibutton(app.figure1, 'push');
            app.comput.Text = 'Execute';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Pulse parameters';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'sample''s visible n file';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'front window''s visible n file';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'front window''s IR n file';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'front win''s extinction file (cm-1)';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'back window''s visible n file';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'back window''s IR n file';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'back win''s extinction file (cm-1)';
            app.PMTqefile = uieditfield(app.figure1, 'text');
            app.PMTqefile.Tooltip = 'file for determining monochromator/det. efficiency';
            app.PMTqefile.Value = 'respPMT';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'detector''s QE file';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'sample''s IR n file';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'sample''s extinction file (cm-1)';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = {'OUTPUT FILE  (adds x,y,z,p.txt to end)'; ''};
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'M';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'M';
            app.Nref = uieditfield(app.figure1, 'text');
            app.Nref.Value = '15.1';
            app.text20 = uilabel(app.figure1);
            app.text20.Text = 'M';
            app.text21 = uilabel(app.figure1);
            app.text21.Text = 'sample conc.';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'solvent (bg) conc.';
            app.text23 = uilabel(app.figure1);
            app.text23.Text = 'ref. conc.';
            app.text24 = uilabel(app.figure1);
            app.text24.Text = 'Nyquist freq.';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'resolution';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'w2 beginning';
            app.w2end = uieditfield(app.figure1, 'text');
            app.w2end.Value = '1560';
            app.w2sp = uieditfield(app.figure1, 'text');
            app.w2sp.Value = '5';
            app.text30 = uilabel(app.figure1);
            app.text30.Text = 'w2 end';
            app.text31 = uilabel(app.figure1);
            app.text31.Text = 'w2 spacing';
            app.text33 = uilabel(app.figure1);
            app.text33.Text = 'A(DOVE-IR1)';
            app.text35 = uilabel(app.figure1);
            app.text35.Text = 'tkness of sample';
            app.text36 = uilabel(app.figure1);
            app.text36.Text = 'theta31 air';
            app.text37 = uilabel(app.figure1);
            app.text37.Text = 'theta21 air';
            app.text38 = uilabel(app.figure1);
            app.text38.Text = 'tkness of front win';
            app.text39 = uilabel(app.figure1);
            app.text39.Text = 'A(front win)';
            app.text40 = uilabel(app.figure1);
            app.text40.Text = 'tkness of back win';
            app.text41 = uilabel(app.figure1);
            app.text41.Text = 'A(back win)';
            app.text45 = uilabel(app.figure1);
            app.text45.Text = 'A(DOVE-IR2)';
            app.text46 = uilabel(app.figure1);
            app.text46.Text = 'A(DOVE-Raman)';
            app.text47 = uilabel(app.figure1);
            app.text47.Text = 'A(SIVE-w2)';
            app.ACARS = uieditfield(app.figure1, 'text');
            app.ACARS.Value = '0';
            app.text48 = uilabel(app.figure1);
            app.text48.Text = 'A(CARS)';
            app.text49 = uilabel(app.figure1);
            app.text49.Text = 'Gam(cg)';
            app.text50 = uilabel(app.figure1);
            app.text50.Text = 'Gam(bg)';
            app.anharmw1 = uieditfield(app.figure1, 'text');
            app.anharmw1.Tooltip = 'If a Lorentzian hot band is present.  Positive values mean hot band is lower in freq. than main.';
            app.anharmw1.Value = '0.0';
            app.anharmw2 = uieditfield(app.figure1, 'text');
            app.anharmw2.Tooltip = 'See note for hotband anharm w1.';
            app.anharmw2.Value = '0.0';
            app.Ratio = uieditfield(app.figure1, 'text');
            app.Ratio.Tooltip = 'ratios of the A coefficients of the hotband to that of the main band.';
            app.Ratio.Value = '0';
            app.text51 = uilabel(app.figure1);
            app.text51.Text = 'hotband anharm w1';
            app.text52 = uilabel(app.figure1);
            app.text52.Text = {'hotband anharm w2'; ''};
            app.text53 = uilabel(app.figure1);
            app.text53.Text = 'Ratio(hotband/main)';
            app.text54 = uilabel(app.figure1);
            app.text54.Text = 'Gam(ag)';
            app.text55 = uilabel(app.figure1);
            app.text55.Text = {'Correlation(range [-1,1])'; ''};
            app.text56 = uilabel(app.figure1);
            app.text56.Text = 'wcg';
            app.text57 = uilabel(app.figure1);
            app.text57.Text = 'wbg';
            app.text58 = uilabel(app.figure1);
            app.text58.Text = 'wag';
            app.text59 = uilabel(app.figure1);
            app.text59.Text = 'A(CARS-ref.)';
            app.text60 = uilabel(app.figure1);
            app.text60.Text = 'A(CARS-alt.)';
            app.text61 = uilabel(app.figure1);
            app.text61.Text = 'A(backgr)';
            app.text62 = uilabel(app.figure1);
            app.text62.Text = 'wag-ref.';
            app.text63 = uilabel(app.figure1);
            app.text63.Text = 'wag-alt.';
            app.text64 = uilabel(app.figure1);
            app.text64.Text = 'Gam(ag-ref.)';
            app.text65 = uilabel(app.figure1);
            app.text65.Text = 'Gam(ag-alt.)';
            app.text66 = uilabel(app.figure1);
            app.text66.Text = 'IFWHM(w1)';
            app.text67 = uilabel(app.figure1);
            app.text67.Text = 'IFWHM(w2)';
            app.text68 = uilabel(app.figure1);
            app.text68.Text = 'IFWHM(w3)';
            app.text69 = uilabel(app.figure1);
            app.text69.Text = 'delay(2-1)';
            app.text70 = uilabel(app.figure1);
            app.text70.Text = 'delay(3-2)';
            app.text71 = uilabel(app.figure1);
            app.text71.Text = 'ps';
            app.text72 = uilabel(app.figure1);
            app.text72.Text = 'ps';
            app.text73 = uilabel(app.figure1);
            app.text73.Text = 'ps';
            app.text74 = uilabel(app.figure1);
            app.text74.Text = 'ps';
            app.text75 = uilabel(app.figure1);
            app.text75.Text = 'ps';
            app.text76 = uilabel(app.figure1);
            app.text76.Text = 'cm-1';
            app.text78 = uilabel(app.figure1);
            app.text78.Text = 'cm-1';
            app.text79 = uilabel(app.figure1);
            app.text79.Text = 'cm-1';
            app.text80 = uilabel(app.figure1);
            app.text80.Text = 'cm-1';
            app.text84 = uilabel(app.figure1);
            app.text84.Text = 'cm-1';
            app.text85 = uilabel(app.figure1);
            app.text85.Text = 'cm-1';
            app.text86 = uilabel(app.figure1);
            app.text86.Text = 'cm-1';
            app.text87 = uilabel(app.figure1);
            app.text87.Text = 'cm-1';
            app.text88 = uilabel(app.figure1);
            app.text88.Text = 'cm-1';
            app.text89 = uilabel(app.figure1);
            app.text89.Text = 'cm-1';
            app.text90 = uilabel(app.figure1);
            app.text90.Text = 'cm-1';
            app.text93 = uilabel(app.figure1);
            app.text93.Text = {'cm-1'; ''};
            app.text94 = uilabel(app.figure1);
            app.text94.Text = 'cm-1';
            app.text98 = uilabel(app.figure1);
            app.text98.Text = 'cm-1';
            app.text99 = uilabel(app.figure1);
            app.text99.Text = 'cm-1';
            app.text100 = uilabel(app.figure1);
            app.text100.Text = 'cm-1';
            app.text101 = uilabel(app.figure1);
            app.text101.Text = 'cm';
            app.text102 = uilabel(app.figure1);
            app.text102.Text = 'cm';
            app.text103 = uilabel(app.figure1);
            app.text103.Text = 'cm';
            app.text105 = uilabel(app.figure1);
            app.text105.Text = {'front win'; ''};
            app.text106 = uilabel(app.figure1);
            app.text106.Text = {'back win'; ''};
            app.text107 = uilabel(app.figure1);
            app.text107.Text = 'deg';
            app.text108 = uilabel(app.figure1);
            app.text108.Text = 'deg';
            app.text111 = uilabel(app.figure1);
            app.text111.Text = 'cm-1';
            app.text112 = uilabel(app.figure1);
            app.text112.Text = 'cm-1';
            app.text113 = uilabel(app.figure1);
            app.text113.Text = {'DOVE frequency scan ultrafast simulation program'; 'inhomogeneous + homogeneous treatment'; ''};
            app.text114 = uilabel(app.figure1);
            app.text114.Text = '.txt';
            app.text115 = uilabel(app.figure1);
            app.text115.Text = '.txt';
            app.text116 = uilabel(app.figure1);
            app.text116.Text = '.txt';
            app.text117 = uilabel(app.figure1);
            app.text117.Text = '.txt';
            app.text118 = uilabel(app.figure1);
            app.text118.Text = '.txt';
            app.text119 = uilabel(app.figure1);
            app.text119.Text = '.txt';
            app.text120 = uilabel(app.figure1);
            app.text120.Text = '.txt';
            app.text121 = uilabel(app.figure1);
            app.text121.Text = '.txt';
            app.text122 = uilabel(app.figure1);
            app.text122.Text = '.txt';
            app.text123 = uilabel(app.figure1);
            app.text123.Text = '.txt';
            app.text124 = uilabel(app.figure1);
            app.text124.Text = '.txt';
            app.text126 = uilabel(app.figure1);
            app.text126.Text = '*';
            app.text127 = uilabel(app.figure1);
            app.text127.Text = '*';
            app.text128 = uilabel(app.figure1);
            app.text128.Text = '*';
            app.text130 = uilabel(app.figure1);
            app.text130.Text = '*';
            app.text131 = uilabel(app.figure1);
            app.text131.Text = '*';
            app.text132 = uilabel(app.figure1);
            app.text132.Text = '*';
            app.text133 = uilabel(app.figure1);
            app.text133.Text = '*';
            app.text137 = uilabel(app.figure1);
            app.text137.Text = {'objects with '; 'asterisks (*) will'; 'speed up code if'; 'set to zero.'};
            app.Nalt = uieditfield(app.figure1, 'text');
            app.Nalt.Value = '15.1';
            app.text138 = uilabel(app.figure1);
            app.text138.Text = 'alt. conc.';
            app.text139 = uilabel(app.figure1);
            app.text139.Text = 'M';
            app.text140 = uilabel(app.figure1);
            app.text140.Text = {'save parameters'; '(hold on button '; 'for more info)'};
            app.w3 = uieditfield(app.figure1, 'text');
            app.w3.Value = '12640';
            app.text141 = uilabel(app.figure1);
            app.text141.Text = 'cm-1';
            app.text142 = uilabel(app.figure1);
            app.text142.Text = 'w3';
            app.loadparams = uieditfield(app.figure1, 'text');
            app.loadparams.Value = 'output';
            app.text143 = uilabel(app.figure1);
            app.text143.Text = '.txt';
            app.toggleload = uibutton(app.figure1, 'push');
            app.toggleload.Tooltip = 'loads Matlab formatted parameter file only.';
            app.toggleload.Text = 'Load Parameters';
            app.text144 = uilabel(app.figure1);
            app.text144.Text = 'filename:';
            app.text146 = uilabel(app.figure1);
            app.text146.Text = {'Data file for lsq and slices (adds x.txt, y.txt, z.txt)'; ''};
            app.text147 = uilabel(app.figure1);
            app.text147.Text = '.txt';
            app.datafile = uieditfield(app.figure1, 'text');
            app.datafile.Value = 'dataz';
            app.text148 = uilabel(app.figure1);
            app.text148.Text = 'data?';
            app.text149 = uilabel(app.figure1);
            app.text149.Text = {'sample'; ''};
            app.text150 = uilabel(app.figure1);
            app.text150.Text = 'popn only';
            app.etaab = uieditfield(app.figure1, 'text');
            app.etaab.Value = '0';
            app.text151 = uilabel(app.figure1);
            app.text151.Text = 'Gam(cb) (should be similar to Gam(cg)+Gam(bg))';
            app.lambdabg = uieditfield(app.figure1, 'text');
            app.lambdabg.Value = '0';
            app.lambdaag = uieditfield(app.figure1, 'text');
            app.lambdaag.Value = '0';
            app.kappaag = uieditfield(app.figure1, 'text');
            app.kappaag.Value = '0';
            app.kappabg = uieditfield(app.figure1, 'text');
            app.kappabg.Value = '0';
            app.text152 = uilabel(app.figure1);
            app.text152.Text = 'cm-1';
            app.text153 = uilabel(app.figure1);
            app.text153.Text = 'cm-1';
            app.text154 = uilabel(app.figure1);
            app.text154.Text = 'lambda(bg)';
            app.text155 = uilabel(app.figure1);
            app.text155.Text = 'kappa(bg)';
            app.text156 = uilabel(app.figure1);
            app.text156.Text = 'kappa(ag)';
            app.text157 = uilabel(app.figure1);
            app.text157.Text = 'lambda(ag)';
            app.text158 = uilabel(app.figure1);
            app.text158.Text = {'for no correlation, correl=0, and '; 'lambda(bg) does not have to '; 'equal lambda(bg). Otherwise,'; 'both lambdas must be equal.'};
            app.kappa = uieditfield(app.figure1, 'text');
            app.kappa.Value = '0';
            app.FWHM = uieditfield(app.figure1, 'text');
            app.FWHM.Value = '0';
            app.lambda = uieditfield(app.figure1, 'text');
            app.lambda.Value = '0';
            app.lamcmpt = uibutton(app.figure1, 'push');
            app.lamcmpt.Text = 'Compute';
            app.text159 = uilabel(app.figure1);
            app.text159.Text = 'Lambda calculator';
            app.text160 = uilabel(app.figure1);
            app.text160.Text = 'cm-1';
            app.text161 = uilabel(app.figure1);
            app.text161.Text = 'cm-1';
            app.text163 = uilabel(app.figure1);
            app.text163.Text = 'and a linewidth FWHM of:';
            app.edit101 = uilabel(app.figure1);
            app.edit101.Text = 'Given a kappa of:';
            app.text165 = uilabel(app.figure1);
            app.text165.Text = 'then lambda should be:';
            app.path = uieditfield(app.figure1, 'text');
            app.path.Value = 'c:\test\';
            app.text166 = uilabel(app.figure1);
            app.text166.Text = 'path for samp,win,QE file (incl. backslash):';
            app.delta = uieditfield(app.figure1, 'text');
            app.delta.Value = '0';
            app.pure = uieditfield(app.figure1, 'text');
            app.pure.Value = '0';
            app.text167 = uilabel(app.figure1);
            app.text167.Text = 'cm-1';
            app.text168 = uilabel(app.figure1);
            app.text168.Text = 'cm-1';
            app.text169 = uilabel(app.figure1);
            app.text169.Text = {'If K<<1,'; 'delta='};
            app.text170 = uilabel(app.figure1);
            app.text170.Text = {'If K >>1'; 'gamstar='};
            app.saveparams = uicheckbox(app.figure1);
            app.saveparams.Text = '';
            app.leastsquareson = uicheckbox(app.figure1);
            app.leastsquareson.Text = '';
            app.E1flag0 = uicheckbox(app.figure1);
            app.E1flag0.Text = '';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.E1flag1 = uieditfield(app.figure1, 'numeric');
            app.E1flag1.Limits = [0 2];
            app.E1flag1.RoundFractionalValues = 'on';
            app.E1flag1.ValueDisplayFormat = '%1g';
            app.E2flag0 = uicheckbox(app.figure1);
            app.E2flag0.Text = '';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '';
            app.E2flag1 = uieditfield(app.figure1, 'numeric');
            app.E2flag1.Limits = [0 2];
            app.E2flag1.RoundFractionalValues = 'on';
            app.E2flag1.ValueDisplayFormat = '%1g';
            app.text171 = uitextarea(app.figure1);
            app.text171.Value = {'Exponential?'};
            app.text171_2 = uitextarea(app.figure1);
            app.text171_2.Value = {'Exptl?'};
            app.text171_3 = uitextarea(app.figure1);
            app.text171_3.Tooltip = {'0 if dual sided, 1 if rising in time, 2 if falling'};
            app.text171_3.Value = {'Type of exptl:'};
            app.text171_4 = uitextarea(app.figure1);
            app.text171_4.Value = {'type:'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOVE_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @DOVE_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end